classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Menu      matlab.ui.container.Menu
        Menu_4    matlab.ui.container.Menu
        Menu_5    matlab.ui.container.Menu
        Menu_2    matlab.ui.container.Menu
        Menu_3    matlab.ui.container.Menu
        UIAxes    matlab.ui.control.UIAxes
        UITable   matlab.ui.control.Table
        Button    matlab.ui.control.StateButton
        Button_2  matlab.ui.control.Button
        Button_3  matlab.ui.control.Button
    end


    methods (Access = private)
    
        function results = dosome(app)
            group = {'Lewis' 'Bold' 'Newshark' 'Tom&Jerry'};
            t = app.UITable;
            t.ColumnFormat = {group 'numeric'};
            t.ColumnEditable = true;
            d = {'Lewis' 1};
            t.Data = d;
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            dosome(app);
        end

        % Value changed function: Button
        function ButtonValueChanged(app, event)
            value = app.Button.Value;
            dosome(app);
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            data = app.UITable.Data;
%             app.TextArea.Value = data;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            data = app.UITable.Data;
            l = size(data,1);
            data{l+1,1} = 'Lewis';
            data{l+1,2} = 1;
            app.UITable.Data = data;
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            data = app.UITable.Data;
            l = size(data,1);
            if (l > 1)
                data(l,:)=[];
                app.UITable.Data = data;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = '文件';
            app.Menu_4 = uimenu(app.Menu);
            app.Menu_4.Text = '新建';
            app.Menu_5 = uimenu(app.Menu);
            app.Menu_5.Text = '保存';
            app.Menu_2 = uimenu(app.UIFigure);
            app.Menu_2.Text = '自定义';
            app.Menu_3 = uimenu(app.UIFigure);
            app.Menu_3.Text = '帮助';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'类型'; '厚度'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Text = '还原';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '+';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '-';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end